classdef Quest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Label     matlab.ui.control.Label
        Chislo    matlab.ui.control.NumericEditField
        Chose     matlab.ui.control.Button
        Edit      matlab.ui.control.Button
    end

    
    properties (Access = public)
        value;
        path;
        name;
        fl = 0;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Chislo
        function ChisloValueChanged(app, event)
            app.value = app.Chislo.Value;
            app.fl = 1;
        end

        % Button pushed function: Chose
        function ChosePushed(app, event)
            [app.name,app.path] = uigetfile({'*.xlsb; *.xlsm; *.xlsx; *.xltm; *.xltx;*.ods;*.xls',...
                'Tables(*.xls,*.xlsb,*.xlsm,*.xlsx,*.xltm,*.xltx,*.ods'},...
               'Select a file');
        end

        % Button pushed function: Edit
        function EditPushed(app, event)
            if app.path == 0
                errordlg('Please,choose a file','File Error');
            elseif app.Chislo.Value == -1
                errordlg('Please,choose a number','Number Error');
            else
                if app.fl
                    t = readtable([app.path,app.name],'ReadVariableNames',false,'Format','auto','Sheet',1);
                    start = find(t.Var1==app.value)+1;
                    t4 = ones(size(t.Var1,1),1);
                    t4(:) = t4(:)*(-1);
                    if ~isempty(start)
                        for i = start:size(t.Var1)
                            k = 2;
                            while k < size(t.Var1,1) && (t.Var3(i)>=t.Var2(k)) 
                                k = k+1;
                            end
                            if k < size(t.Var1,1)
                                t4(i) = i - k + 1;
                            else
                                t4(i) = -1;
                            end
                        end
                        t.Var4 = t4;
                        writetable(t,[app.path,app.name],'Sheet',1);
                        f = msgbox('Operation Completed','Success');
                    else
                        errordlg('Please,choose a number from 1 coloumn','Number Error');
                    end
                else
                    errordlg('Please,choose a file','File Error');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Введите номер строки';
            app.Chislo = uieditfield(app.UIFigure, 'numeric');
            app.Chislo.ValueChangedFcn = createCallbackFcn(app, @ChisloValueChanged, true);
            app.Chislo.Value = -1;
            app.Chose = uibutton(app.UIFigure, 'push');
            app.Chose.ButtonPushedFcn = createCallbackFcn(app, @ChosePushed, true);
            app.Chose.Text = 'Нажмите для выбора файла';
            app.Edit = uibutton(app.UIFigure, 'push');
            app.Edit.ButtonPushedFcn = createCallbackFcn(app, @EditPushed, true);
            app.Edit.Text = 'Нажмите для изменения таблицы';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Quest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end