classdef ege < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Button       matlab.ui.control.Button
        Label        matlab.ui.control.Label
        Label_3      matlab.ui.control.Label
        Label_4      matlab.ui.control.Label
        EditField    matlab.ui.control.EditField
        EditField_2  matlab.ui.control.EditField
        EditField_3  matlab.ui.control.EditField
        Label_5      matlab.ui.control.Label
        Label_6      matlab.ui.control.Label
        Label_7      matlab.ui.control.Label
        EditField_4  matlab.ui.control.NumericEditField
        EditField_5  matlab.ui.control.NumericEditField
        EditField_6  matlab.ui.control.NumericEditField
        Label_8      matlab.ui.control.Label
        Label_9      matlab.ui.control.Label
        Label_10     matlab.ui.control.Label
        Label_11     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
           int16 A ;
           if (app.EditField_4.Value > 100 || app.EditField_4.Value < 0)
               error('Баллы от 0 до 100!');
           end
           if (app.EditField_5.Value > 100 || app.EditField_5.Value < 0)
               errordlg('Баллы от 0 до 100!');
           end
           if (app.EditField_6.Value > 100 || app.EditField_6.Value < 0)
               errordlg('Баллы от 0 до 100!');
           end
           A = app.EditField_4.Value + app.EditField_5.Value + app.EditField_6.Value;
           app.Label_11.Text = num2str(A);
           if (A < 250)
               app.Label_10.Text = 'СОЖАЛЕЕМ';
           else
               app.Label_10.Text = 'ПОЗДРАВЛЯЕМ';
           end
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Вычислить';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'ФИО';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'ПРЕДМЕТ';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = {'БАЛЛЫ ЕГЭ'; ''};
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = {'МАТЕМАТИКА'; ''; ''};
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'ИНФОРМАТИКА';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = {'РУССКИЙ'; 'ЯЗЫК'};
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = 'СУММА БАЛЛОВ';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = 'Проходной балл : 250';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ege

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end