classdef Task_interf_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        ButtonGroup  matlab.ui.container.ButtonGroup
        CheckBox     matlab.ui.control.CheckBox
        CheckBox_2   matlab.ui.control.CheckBox
        Button       matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            delete(app.UIAxes);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 15];
            app.UIAxes.YLim = [-1 1];
            app.UIAxes.XTick = [0 5 10 15];
            app.UIAxes.XTickLabel = {'0'; '5'; '10'; '15'};
            app.UIAxes.YTick = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTickLabel = {'-1'; '-0.8'; '-0.6'; '-0.4'; '-0.2'; '0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.Position = [22 113 296 313];
            hold (app.UIAxes,'on');
            if(app.CheckBox.Value)
            X = linspace(0,15,10000);
            Y = sin(X);
            h1 = plot(app.UIAxes,X,Y,"Color",'blue');
            end
            if(app.CheckBox_2.Value)
            X = linspace(0,15,10000);
            Y = cos(X);          
            h2 = plot(app.UIAxes,X,Y,"Color",'green');
            end
            hold (app.UIAxes,'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Группа переключателей';
            app.CheckBox = uicheckbox(app.ButtonGroup);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = 'Синус';
            app.CheckBox_2 = uicheckbox(app.ButtonGroup);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = 'Косинус';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Построить';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 15];
            app.UIAxes.YLim = [-1 1];
            app.UIAxes.XTick = [0 5 10 15];
            app.UIAxes.XTickLabel = {'0'; '5'; '10'; '15'};
            app.UIAxes.YTick = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTickLabel = {'-1'; '-0.8'; '-0.6'; '-0.4'; '-0.2'; '0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Task_interf_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end