classdef Task_interf_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Build_exp   matlab.ui.control.Button
        Build_sin   matlab.ui.control.Button
        Delete_exp  matlab.ui.control.Button
        Delete_sin  matlab.ui.control.Button
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        h1 % Description
        h2
        d1 = 0;
        d2 = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Build_exp
        function Build_expPushed(app, event)
            if ~app.d1 
                hold (app.UIAxes,"on");
                %app.UIAxes.NextPlot = 'add';
                app.d1 = 1;
                X = -2:0.2:2;
                Y = exp(-X.^2);
                app.h1 = plot(app.UIAxes,X,Y,"Color",'blue');
                hold (app.UIAxes,"off");
            end
        end

        % Button pushed function: Build_sin
        function Build_sinPushed(app, event)
            if ~app.d2 
                hold (app.UIAxes,"on");
                %app.UIAxes.NextPlot = 'add';
                app.d2 = 1;
                X1 = 0:0.1:4*pi;
                Y1 = sin(X1);
                app.h2 = plot(app.UIAxes,X1,Y1,"Color",'green');
                hold (app.UIAxes,"off");
            end
        end

        % Button pushed function: Delete_exp
        function Delete_expPushed(app, event)
            if app.d1
                hold (app.UIAxes,"on");
                %app.UIAxes.NextPlot = 'add';
                delete(app.h1);
                hold (app.UIAxes,"off");
                app.d1 = 0;
            end
        end

        % Button pushed function: Delete_sin
        function Delete_sinPushed(app, event)
            if app.d2
                hold (app.UIAxes,"on");
                %app.UIAxes.NextPlot = 'add';
                delete(app.h2);
                hold (app.UIAxes,"off");
                app.d2 = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Build_exp = uibutton(app.UIFigure, 'push');
            app.Build_exp.ButtonPushedFcn = createCallbackFcn(app, @Build_expPushed, true);
            app.Build_exp.Text = 'Построить y=exp(-x^2)';
            app.Build_sin = uibutton(app.UIFigure, 'push');
            app.Build_sin.ButtonPushedFcn = createCallbackFcn(app, @Build_sinPushed, true);
            app.Build_sin.Text = 'Построить y1=sin(x1)';
            app.Delete_exp = uibutton(app.UIFigure, 'push');
            app.Delete_exp.ButtonPushedFcn = createCallbackFcn(app, @Delete_expPushed, true);
            app.Delete_exp.Text = 'Очистить y=exp(-x^2)';
            app.Delete_sin = uibutton(app.UIFigure, 'push');
            app.Delete_sin.ButtonPushedFcn = createCallbackFcn(app, @Delete_sinPushed, true);
            app.Delete_sin.Text = 'Очистить y1=sin(x1)';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Task_interf_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end